classdef midi64nicator_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        Convert         matlab.ui.control.Button
        Information     matlab.ui.control.Button
        NecessaryPanel  matlab.ui.container.Panel
        SwapNoteOffs    matlab.ui.control.CheckBox
        MetaTimes       matlab.ui.control.CheckBox
        StripInfo       matlab.ui.control.CheckBox
        ZeroRunMode     matlab.ui.control.CheckBox
        AddTempo        matlab.ui.control.CheckBox
        OptionalPanel   matlab.ui.container.Panel
        ReChannel       matlab.ui.control.CheckBox
        TruncateEnd     matlab.ui.control.CheckBox
        NonLooping      matlab.ui.control.CheckBox
    end


    properties (Access = public)
        CurrentPath = []; % Current directory public property to pass between apps
    end


    methods (Access = private)

        % Code that executes after component creation
        function midi64nicator_OpeningFcn(app)
            % mostly need this to get window in a reasonable position
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            
            pos = get(app.figure1, 'Position');
            width = pos(3);
            height = pos(4);
            
            left = round(screenWidth/2 - pos(3)/2);
            bottom = round(screenHeight/2 - pos(4)/2);
            drawnow;
            app.figure1.Position = [left bottom width height]; 
            
        end

        % Button pushed function: Convert
        function Convert_Callback(app, event)

            % Open dialog to get file location
            [file,path] = uigetfile({'*.mid;*.midi','MIDI Files (*.mid,*.midi)';'*.*','All Files (*.*)'},'Select a MIDI',app.CurrentPath);
            
            if file ~= 0
                % Read midi file
                app.CurrentPath = path; % set current path
                f = waitbar(0,'Loading MIDI');
                midi = readmidi([app.CurrentPath file]);
            
                % Convert midi to D64 format
                waitbar(0.5,f,'64nicating MIDI');
                midi = d64convert(midi,get(app.SwapNoteOffs,'Value'),get(app.MetaTimes,'Value'),get(app.StripInfo,'Value'),get(app.ZeroRunMode,'Value'),get(app.AddTempo,'Value'),get(app.ReChannel,'Value'),get(app.TruncateEnd,'Value'),get(app.NonLooping,'Value'));
            
                % Select destination
                waitbar(1,f,'Saving');
                close(f)
                [file,path] = uiputfile({'*.mid;*.midi','MIDI Files (*.mid,*.midi)';'*.*','All Files (*.*)'},'Save the D64 MIDI',[app.CurrentPath '64nicated.mid']);
            
                if file ~= 0
                    % Write the file
                    writemidi(midi,[path file]);
                end
            end
            
        end

        % Button pushed function: Information
        function Information_Callback(app, event)
            % hObject    handle to Information (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Information message
            Message(1) = {'MIDI 64nicator Version 1.2'};
            Message(2) = {''};
            Message(3) = {'Conversion Coding: Immorpher (https://www.youtube.com/c/Immorpher)'};
            Message(4) = {'D64 MIDI Decoding: Impboy, Anomalous Horse'};
            Message(5) = {'MIDI In/Out: Ken Schutte (https://kenschutte.com/midi)'};
            Message(6) = {''};
            Message(7) = {'Doom 64 Discord: https://discord.gg/Ktxz8nz'};
            
            f = msgbox(Message,'Information');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'midi64nicator';
            app.Convert = uibutton(app.figure1, 'push');
            app.Convert.ButtonPushedFcn = createCallbackFcn(app, @Convert_Callback, true);
            app.Convert.Text = 'Convert';
            app.Information = uibutton(app.figure1, 'push');
            app.Information.ButtonPushedFcn = createCallbackFcn(app, @Information_Callback, true);
            app.Information.Text = 'Information';
            app.NecessaryPanel = uipanel(app.figure1);
            app.NecessaryPanel.Title = 'Necessary Processing';
            app.SwapNoteOffs = uicheckbox(app.NecessaryPanel);
            app.SwapNoteOffs.Text = 'Swap Note Offs';
            app.SwapNoteOffs.Value = true;
            app.MetaTimes = uicheckbox(app.NecessaryPanel);
            app.MetaTimes.Text = 'Insert Loop Times';
            app.MetaTimes.Value = true;
            app.StripInfo = uicheckbox(app.NecessaryPanel);
            app.StripInfo.Text = 'Strip Info Events';
            app.StripInfo.Value = true;
            app.ZeroRunMode = uicheckbox(app.NecessaryPanel);
            app.ZeroRunMode.Text = 'Zero Run Mode';
            app.ZeroRunMode.Value = true;
            app.AddTempo = uicheckbox(app.NecessaryPanel);
            app.AddTempo.Text = 'Add Tempo';
            app.AddTempo.Value = true;
            app.OptionalPanel = uipanel(app.figure1);
            app.OptionalPanel.Title = 'Optional Processing';
            app.ReChannel = uicheckbox(app.OptionalPanel);
            app.ReChannel.Text = 'Re-Channel';
            app.ReChannel.Value = true;
            app.TruncateEnd = uicheckbox(app.OptionalPanel);
            app.TruncateEnd.Text = 'Truncate End';
            app.TruncateEnd.Value = true;
            app.NonLooping = uicheckbox(app.OptionalPanel);
            app.NonLooping.Text = 'Non-Looping';
        end
    end

    methods (Access = public)

        % Construct app
        function app = midi64nicator_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @midi64nicator_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end